classdef Projectile_Simulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Versionlabel                matlab.ui.control.Label
        ProjectileSimulatorLabel    matlab.ui.control.Label
        TabGroup                    matlab.ui.container.TabGroup
        simulator                   matlab.ui.container.Tab
        simlayout                   matlab.ui.container.GridLayout
        LocationTrackerPanel        matlab.ui.container.Panel
        locate                      matlab.ui.control.Button
        secLabel                    matlab.ui.control.Label
        locationdisp                matlab.ui.control.TextArea
        timelocate                  matlab.ui.control.NumericEditField
        EnterthetimetocheckthelocationoftheobjectEditFieldLabel  matlab.ui.control.Label
        OutputPanel                 matlab.ui.container.Panel
        mLabel_3                    matlab.ui.control.Label
        mLabel_2                    matlab.ui.control.Label
        secLabel_2                  matlab.ui.control.Label
        MH                          matlab.ui.control.NumericEditField
        MaxHieghtLabel              matlab.ui.control.Label
        peak                        matlab.ui.control.EditField
        PeakLocationEditFieldLabel  matlab.ui.control.Label
        range                       matlab.ui.control.NumericEditField
        RangeLabel                  matlab.ui.control.Label
        timeofflight                matlab.ui.control.NumericEditField
        TimeofflightLabel           matlab.ui.control.Label
        InputPanel                  matlab.ui.container.Panel
        degLabel                    matlab.ui.control.Label
        mLabel                      matlab.ui.control.Label
        msLabel                     matlab.ui.control.Label
        RTSSSwitch                  matlab.ui.control.Switch
        RTSSSwitchLabel             matlab.ui.control.Label
        resetall                    matlab.ui.control.Button
        simulate                    matlab.ui.control.Button
        acceleration                matlab.ui.control.DropDown
        AccelerationLabel           matlab.ui.control.Label
        Hieght                      matlab.ui.control.NumericEditField
        IntialHeightLabel           matlab.ui.control.Label
        angle                       matlab.ui.control.NumericEditField
        AngleofLaunchLabel          matlab.ui.control.Label
        velocity                    matlab.ui.control.NumericEditField
        VelocityLabel               matlab.ui.control.Label
        RTSSPanel                   matlab.ui.container.Panel
        ReplayButton                matlab.ui.control.Button
        FastRewindButton            matlab.ui.control.Button
        RewindButton                matlab.ui.control.Button
        PlayPauseButton             matlab.ui.control.StateButton
        ForwardButton               matlab.ui.control.Button
        FastForwardButton           matlab.ui.control.Button
        InputPlaybackSpeed          matlab.ui.control.Spinner
        MainGraph                   matlab.ui.control.UIAxes
        GraphsTab                   matlab.ui.container.Tab
        HistoryTab                  matlab.ui.container.Tab
    end


    methods (Access = private)

        function [V,alpha,g] = input(app) % This is the input function of the program
            V = app.velocity.Value; % Velocity
            alpha =  deg2rad(app.angle.Value); % Angle in radians
            a = app.acceleration.Value; % Acceleration ie. Gravity
            if a == "Earth"
                g = 9.81; % Earth Gravity
            elseif a == "Moon"
                g = 1.62; % Moon Gravity
            elseif a == "Mars"
                g = 3.71; % Mars Gravity
            end
        end

        function [tofflight,max_distance,y_max_height] = sim(app) % runs the simulation function 
            [V,alpha,g] = input(app);

            tofflight = (2*V*sin(alpha)/g); % Calculates the time of flight 
            app.timeofflight.Value = tofflight;

            max_distance = V * cos(alpha) * tofflight; % Calculates the Range
            app.range.Value = max_distance;

            t_max_height = V * sin(alpha) / g; 
            y_max_height = V * sin(alpha) * t_max_height - 0.5 * g * t_max_height^2; % Max Hieght Y - Coordinates
            x_max_height = V * cos(alpha) * t_max_height - 0.5 * g * t_max_height^2; % Max Hieght X - Coordinates
            app.peak.Value = ['(',num2str(x_max_height),',',num2str(y_max_height),')'];
            app.MH.Value = y_max_height; % Displays Max Hieght

            app.locate.Enable ="on"; % Enables the locate button
            app.timelocate.Editable = "on"; % Enables the Time field

            if app.RTSSSwitch.Value == "On" % Check the RTSS switch if it is ON and enables the RTSS panel
                app.rtss(app);
                app.RTSSPanel.Enable = "on";
                app.ReplayButton.Enable = "on";
                app.FastForwardButton.Enable = "on";
                app.FastRewindButton.Enable= "on";
                app.ReplayButton.Enable= "on";
                app.RewindButton.Enable= "on";
                app.PlayPauseButton.Enable = "on";
                app.ForwardButton.Enable= "on";
                app.InputPlaybackSpeed.Enable= "on";
            else % If off then disables the panel along with its buttons
                app.plotgraph;
                app.RTSSPanel.Enable = "off";
                app.ReplayButton.Enable = "off";
                app.FastForwardButton.Enable = "off";
                app.FastRewindButton.Enable= "off";
                app.ReplayButton.Enable= "off";
                app.RewindButton.Enable= "off";
                app.PlayPauseButton.Enable = "off";
                app.ForwardButton.Enable= "off";
                app.InputPlaybackSpeed.Enable= "off";
            end

        end

        function pg = plotgraph(app) % Plots the static Graph

            [V,alpha,g] = input(app);
            t = linspace(0, 2 * V * sin(alpha) / g, 100); % Time array
            x = V * cos(alpha) * t; % X - Axis
            y = V * sin(alpha) * t - 0.5 * g * t.^2; % Y - Axis

            plot(app.MainGraph,x,y,grid("on"))
        end


        function lw = locatew(app) % The function locates the projectile in x,y coordinaties in a given time period
            [tofflight] = sim(app);
            [V,alpha,g] = input(app);
            t_check = app.timelocate.Value; % Time field
            x_check = V * cos(alpha) * t_check; % X - Location
            y_check = V * sin(alpha) * t_check - 0.5 * g * t_check^2; % Y - Location

            if t_check >= tofflight % if input time is greater or equal to time of flight it corrects the dislay coordnates
                x_check = V * cos(alpha) * tofflight;
                app.locationdisp.Value= ['At time ' num2str(t_check) ' s, the object is at position (' num2str(x_check) ', 0)'];  % Displays time, (x,y) coordinates in locationdisp if time input is greater or equal to time of flight
            else
                app.locationdisp.Value= ['At time ' num2str(t_check) ' s, the object is at position (' num2str(x_check) ', ' num2str(y_check) ')'];  % Displays time, (x,y) coordinates in locationdisp
            end

        end

        function r = reset(app) % This function resets the entire program and disables the RTSS panel and Location panel
            app.velocity.Value = 0;
            app.angle.Value = 0;
            sim(app);
            app.RTSSSwitch.Value = "Off";
            app.peak.Value = "(x,y)";
            app.locate.Enable ="off";
            app.timelocate.Editable = "off";
            app.RTSSPanel.Enable = "off";
            app.ReplayButton.Enable = "off";
            app.FastForwardButton.Enable = "off";
            app.FastRewindButton.Enable= "off";
            app.ReplayButton.Enable= "off";
            app.RewindButton.Enable= "off";
            app.PlayPauseButton.Enable = "off";
            app.ForwardButton.Enable= "off";
            app.InputPlaybackSpeed.Enable= "off";

        end

        
        function results = rtss(app) % The RTSS function (Experimental)
            [V,alpha,g] = input(app);
            t = linspace(0, 2 * V * sin(alpha) / g, 100);
            x = V * cos(alpha) * t; % X - Axis
            y = V * sin(alpha) * t - 0.5 * g * t.^2; % Y - Axis

            tmax = t(1,100);
            xmax = round(V * cos(alpha) * tmax)+1;
            ymax = round(V * sin(alpha) * tmax - 0.5 * g * tmax.^2)+1;

            xr = ceil(xmax / 10) * 10;
            yr = ceil(ymax / 10) * 10;

            for i = 1:length(t)
                plot(app.MainGraph,x(i),y(i),'o',LineWidth=2);
                hold(app.MainGraph,"on")
                plot(app.MainGraph,x(1:i),y(1:i),LineWidth=2);
                hold(app.MainGraph,"off")
                disp([i,x,y])
                axis(app.MainGraph,[0 xr, 0 yr])
                pause(0.01);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: simulate
        function simulateButtonPushed(app, event)
            input(app); % Input Function
            sim(app); % Simulate Function

        end

        % Button pushed function: resetall
        function resetallButtonPushed(app, event)
            reset(app); % Reset Function
        end

        % Button pushed function: locate
        function locateButtonPushed(app, event)
            locatew(app); % Locate function
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.simulator = uitab(app.TabGroup);
            app.simulator.Title = 'Simulator';
            app.simlayout = uigridlayout(app.simulator);
            app.MainGraph = uiaxes(app.simlayout);
            title(app.MainGraph, 'Projectile Motion')
            xlabel(app.MainGraph, 'Horizontal Distance (m)')
            ylabel(app.MainGraph, 'Vertical Distance (m)')
            zlabel(app.MainGraph, 'Z')
            app.RTSSPanel = uipanel(app.simlayout);
            app.RTSSPanel.Enable = 'off';
            app.RTSSPanel.Title = 'Real Time Simulator System ( RTSS )';
            app.InputPlaybackSpeed = uispinner(app.RTSSPanel);
            app.InputPlaybackSpeed.Step = 0.25;
            app.InputPlaybackSpeed.Limits = [0.25 2];
            app.InputPlaybackSpeed.Enable = 'off';
            app.InputPlaybackSpeed.Tooltip = {'Set playback speed'};
            app.InputPlaybackSpeed.Value = 1;
            app.FastForwardButton = uibutton(app.RTSSPanel, 'push');
            app.FastForwardButton.Icon = 'fast-forward.png';
            app.FastForwardButton.Enable = 'off';
            app.FastForwardButton.Text = '';
            app.ForwardButton = uibutton(app.RTSSPanel, 'push');
            app.ForwardButton.Icon = 'Forward.png';
            app.ForwardButton.Enable = 'off';
            app.ForwardButton.Text = '';
            app.PlayPauseButton = uibutton(app.RTSSPanel, 'state');
            app.PlayPauseButton.Enable = 'off';
            app.PlayPauseButton.Icon = 'Play.png';
            app.PlayPauseButton.Text = '';
            app.RewindButton = uibutton(app.RTSSPanel, 'push');
            app.RewindButton.Icon = 'Rewind.png';
            app.RewindButton.Enable = 'off';
            app.RewindButton.Text = '';
            app.FastRewindButton = uibutton(app.RTSSPanel, 'push');
            app.FastRewindButton.Icon = 'FastRewind.png';
            app.FastRewindButton.Enable = 'off';
            app.FastRewindButton.Text = '';
            app.ReplayButton = uibutton(app.RTSSPanel, 'push');
            app.ReplayButton.Enable = 'off';
            app.ReplayButton.Text = 'Replay';
            app.InputPanel = uipanel(app.simlayout);
            app.InputPanel.Title = 'Input  ';
            app.VelocityLabel = uilabel(app.InputPanel);
            app.VelocityLabel.Text = 'Velocity: ';
            app.velocity = uieditfield(app.InputPanel, 'numeric');
            app.AngleofLaunchLabel = uilabel(app.InputPanel);
            app.AngleofLaunchLabel.Text = 'Angle of Launch:';
            app.angle = uieditfield(app.InputPanel, 'numeric');
            app.IntialHeightLabel = uilabel(app.InputPanel);
            app.IntialHeightLabel.Text = 'Initial Height:';
            app.Hieght = uieditfield(app.InputPanel, 'numeric');
            app.AccelerationLabel = uilabel(app.InputPanel);
            app.AccelerationLabel.Text = 'Acceleration:';
            app.acceleration = uidropdown(app.InputPanel);
            app.acceleration.Items = {'Earth', 'Moon', 'Mars'};
            app.acceleration.Value = 'Earth';
            app.simulate = uibutton(app.InputPanel, 'push');
            app.simulate.ButtonPushedFcn = createCallbackFcn(app, @simulateButtonPushed, true);
            app.simulate.Text = 'Simulate';
            app.resetall = uibutton(app.InputPanel, 'push');
            app.resetall.ButtonPushedFcn = createCallbackFcn(app, @resetallButtonPushed, true);
            app.resetall.Text = 'Reset All';
            app.RTSSSwitchLabel = uilabel(app.InputPanel);
            app.RTSSSwitchLabel.Text = 'RTSS';
            app.RTSSSwitch = uiswitch(app.InputPanel, 'slider');
            app.msLabel = uilabel(app.InputPanel);
            app.msLabel.Text = 'm/s';
            app.mLabel = uilabel(app.InputPanel);
            app.mLabel.Text = 'm';
            app.degLabel = uilabel(app.InputPanel);
            app.degLabel.Text = 'deg';
            app.OutputPanel = uipanel(app.simlayout);
            app.OutputPanel.Title = 'Output';
            app.TimeofflightLabel = uilabel(app.OutputPanel);
            app.TimeofflightLabel.Text = 'Time of flight:';
            app.timeofflight = uieditfield(app.OutputPanel, 'numeric');
            app.timeofflight.Editable = 'off';
            app.RangeLabel = uilabel(app.OutputPanel);
            app.RangeLabel.Text = 'Range:';
            app.range = uieditfield(app.OutputPanel, 'numeric');
            app.range.Editable = 'off';
            app.PeakLocationEditFieldLabel = uilabel(app.OutputPanel);
            app.PeakLocationEditFieldLabel.Text = 'Peak Location:';
            app.peak = uieditfield(app.OutputPanel, 'text');
            app.peak.Editable = 'off';
            app.peak.Placeholder = '(x,y)';
            app.MaxHieghtLabel = uilabel(app.OutputPanel);
            app.MaxHieghtLabel.Text = 'Max Hieght:';
            app.MH = uieditfield(app.OutputPanel, 'numeric');
            app.MH.Editable = 'off';
            app.secLabel_2 = uilabel(app.OutputPanel);
            app.secLabel_2.Text = 'sec';
            app.mLabel_2 = uilabel(app.OutputPanel);
            app.mLabel_2.Text = 'm';
            app.mLabel_3 = uilabel(app.OutputPanel);
            app.mLabel_3.Text = 'm';
            app.LocationTrackerPanel = uipanel(app.simlayout);
            app.LocationTrackerPanel.Title = 'Location Tracker';
            app.EnterthetimetocheckthelocationoftheobjectEditFieldLabel = uilabel(app.LocationTrackerPanel);
            app.EnterthetimetocheckthelocationoftheobjectEditFieldLabel.Text = 'Enter the time to check the location of the object: ';
            app.timelocate = uieditfield(app.LocationTrackerPanel, 'numeric');
            app.timelocate.Editable = 'off';
            app.locationdisp = uitextarea(app.LocationTrackerPanel);
            app.locationdisp.Editable = 'off';
            app.locationdisp.Placeholder = 'At time t s, the object is at position x, y';
            app.secLabel = uilabel(app.LocationTrackerPanel);
            app.secLabel.Text = 'sec';
            app.locate = uibutton(app.LocationTrackerPanel, 'push');
            app.locate.ButtonPushedFcn = createCallbackFcn(app, @locateButtonPushed, true);
            app.locate.Enable = 'off';
            app.locate.Text = 'Locate';
            app.GraphsTab = uitab(app.TabGroup);
            app.GraphsTab.Title = 'Graphs';
            app.HistoryTab = uitab(app.TabGroup);
            app.HistoryTab.Title = 'History';
            app.ProjectileSimulatorLabel = uilabel(app.UIFigure);
            app.ProjectileSimulatorLabel.Text = 'Projectile Simulator';
            app.Versionlabel = uilabel(app.UIFigure);
            app.Versionlabel.Text = 'V2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Projectile_Simulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end